classdef Fret_Software < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EnhancedLEDControlUIFigure     matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        NewMenu                        matlab.ui.container.Menu
        LoadMenu                       matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        Console                        matlab.ui.control.EditField
        TabGroup                       matlab.ui.container.TabGroup
        FRETTab                        matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        TabGroup2                      matlab.ui.container.TabGroup
        LED1Tab                        matlab.ui.container.Tab
        LEDSwitch_1                    matlab.ui.control.Switch
        LEDLamp_1                      matlab.ui.control.Lamp
        ActiveSwitchLabel              matlab.ui.control.Label
        IntensitySliderFRET_1          matlab.ui.control.Slider
        IntensitySpinnerFRET_1         matlab.ui.control.Spinner
        IntensitySpinner_2Label_2      matlab.ui.control.Label
        TimeOnmsEditFieldFRET_1        matlab.ui.control.NumericEditField
        TimeOnEditField_2Label_2       matlab.ui.control.Label
        LEDDropDownFRET_1              matlab.ui.control.DropDown
        LEDLabel_2                     matlab.ui.control.Label
        LED2Tab                        matlab.ui.container.Tab
        LEDSwitch_2                    matlab.ui.control.Switch
        LEDLamp_2                      matlab.ui.control.Lamp
        ActiveSwitchLabel_2            matlab.ui.control.Label
        IntensitySliderFRET_2          matlab.ui.control.Slider
        IntensitySpinnerFRET_2         matlab.ui.control.Spinner
        IntensitySpinner_2Label_3      matlab.ui.control.Label
        TimeOnmsEditFieldFRET_2        matlab.ui.control.NumericEditField
        TimeOnEditField_2Label_3       matlab.ui.control.Label
        LEDDropDownFRET_2              matlab.ui.control.DropDown
        SelectedLEDLabel               matlab.ui.control.Label
        LED3Tab                        matlab.ui.container.Tab
        LEDSwitch_3                    matlab.ui.control.Switch
        LEDLamp_3                      matlab.ui.control.Lamp
        ActiveSwitchLabel_3            matlab.ui.control.Label
        IntensitySliderFRET_3          matlab.ui.control.Slider
        IntensitySpinnerFRET_3         matlab.ui.control.Spinner
        IntensitySpinner_2Label_4      matlab.ui.control.Label
        TimeOnmsEditFieldFRET_3        matlab.ui.control.NumericEditField
        TimeOnEditField_2Label_4       matlab.ui.control.Label
        LEDDropDownFRET_3              matlab.ui.control.DropDown
        SelectedLEDLabel_2             matlab.ui.control.Label
        LED4Tab                        matlab.ui.container.Tab
        LEDSwitch_4                    matlab.ui.control.Switch
        LEDLamp_4                      matlab.ui.control.Lamp
        ActiveSwitchLabel_4            matlab.ui.control.Label
        IntensitySliderFRET_4          matlab.ui.control.Slider
        IntensitySpinnerFRET_4         matlab.ui.control.Spinner
        IntensitySpinner_2Label_5      matlab.ui.control.Label
        TimeOnmsEditFieldFRET_4        matlab.ui.control.NumericEditField
        TimeOnEditField_2Label_5       matlab.ui.control.Label
        LEDDropDownFRET_4              matlab.ui.control.DropDown
        SelectedLEDLabel_3             matlab.ui.control.Label
        GeneralTab                     matlab.ui.container.Tab
        FrequencyEditField_2Label      matlab.ui.control.Label
        FrequencyEditFieldFRET         matlab.ui.control.NumericEditField
        TimePausemsEditFieldFRET       matlab.ui.control.NumericEditField
        TimePauseEditField_2Label      matlab.ui.control.Label
        LightpulseTab                  matlab.ui.container.Tab
        LEDSwitchPulse                 matlab.ui.control.Switch
        LEDLampPulse                   matlab.ui.control.Lamp
        ActiveSwitchLabel_5            matlab.ui.control.Label
        EditFieldTimePulse             matlab.ui.control.NumericEditField
        TimeOnEditField_2Label_7       matlab.ui.control.Label
        IntensitySpinnerPulse          matlab.ui.control.Spinner
        IntensitySpinner_2Label_6      matlab.ui.control.Label
        EditFieldTimeDur               matlab.ui.control.NumericEditField
        TimeOnEditField_2Label_6       matlab.ui.control.Label
        LEDDropDownPulse               matlab.ui.control.DropDown
        SelectedLEDLabel_4             matlab.ui.control.Label
        PlotGroup_3                    matlab.ui.container.TabGroup
        LEDRatioTab_1                  matlab.ui.container.Tab
        LEDRatioTab_2                  matlab.ui.container.Tab
        LEDRatioTab_3                  matlab.ui.container.Tab
        LEDRatioTab_4                  matlab.ui.container.Tab
        PlotGroup_2                    matlab.ui.container.TabGroup
        LEDLongTab_1                   matlab.ui.container.Tab
        LEDLongTab_2                   matlab.ui.container.Tab
        LEDLongTab_3                   matlab.ui.container.Tab
        LEDLongTab_4                   matlab.ui.container.Tab
        PlotGroup_1                    matlab.ui.container.TabGroup
        LEDShortTab_1                  matlab.ui.container.Tab
        LEDShortTab_2                  matlab.ui.container.Tab
        LEDShortTab_3                  matlab.ui.container.Tab
        LEDShortTab_4                  matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        TimeEditField                  matlab.ui.control.NumericEditField
        TimeEditFieldLabel             matlab.ui.control.Label
        ListBox                        matlab.ui.control.ListBox
        GoButton                       matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        GridLayout4                    matlab.ui.container.GridLayout
        EditFieldYmax                  matlab.ui.control.NumericEditField
        EditFieldYmin                  matlab.ui.control.NumericEditField
        YmaxLabel                      matlab.ui.control.Label
        YminLabel                      matlab.ui.control.Label
        EditFieldXmax                  matlab.ui.control.NumericEditField
        EditFieldXmin                  matlab.ui.control.NumericEditField
        XmaxLabel                      matlab.ui.control.Label
        XminLabel                      matlab.ui.control.Label
        GridLayout5                    matlab.ui.container.GridLayout
        GridLayout8_3                  matlab.ui.container.GridLayout
        MeanRatio_4                    matlab.ui.control.NumericEditField
        MeanRatio_3                    matlab.ui.control.NumericEditField
        MeanRatio_2                    matlab.ui.control.NumericEditField
        MeanRatio_1                    matlab.ui.control.NumericEditField
        GridLayout8_2                  matlab.ui.container.GridLayout
        MeanLangwellig_4               matlab.ui.control.NumericEditField
        MeanLangwellig_3               matlab.ui.control.NumericEditField
        MeanLangwellig_2               matlab.ui.control.NumericEditField
        MeanLangwellig_1               matlab.ui.control.NumericEditField
        GridLayout8                    matlab.ui.container.GridLayout
        MeanKurzwellig_4               matlab.ui.control.NumericEditField
        MeanKurzwellig_3               matlab.ui.control.NumericEditField
        MeanKurzwellig_2               matlab.ui.control.NumericEditField
        MeanKurzwellig_1               matlab.ui.control.NumericEditField
        MeanRatioLabel                 matlab.ui.control.Label
        MeanslongwaveemissionLabel     matlab.ui.control.Label
        MeansshortwaveemissionLabel    matlab.ui.control.Label
        LEDTab                         matlab.ui.container.Tab
        GridLayout_2                   matlab.ui.container.GridLayout
        LEDforAfterComplexLEDDropDown  matlab.ui.control.DropDown
        LEDforSingleLEDDropDown        matlab.ui.control.DropDown
        LEDforSingleLEDDropDownLabel   matlab.ui.control.Label
        SwitchLEDonafterComplexCheckBox  matlab.ui.control.CheckBox
        StartComplexButton_2           matlab.ui.control.Button
        Label2                         matlab.ui.control.Label
        RepeatsSpinner                 matlab.ui.control.Spinner
        RepeatsSpinnerLabel            matlab.ui.control.Label
        LEDDropDownLabel_2             matlab.ui.control.Label
        TimePauseAllmsEditFieldLED     matlab.ui.control.NumericEditField
        PausebetweenrepeatsLabel       matlab.ui.control.Label
        TimePausemsEditFieldLED_4      matlab.ui.control.NumericEditField
        TimeOnmsEditFieldLED_4         matlab.ui.control.NumericEditField
        IntensitySliderLED_4           matlab.ui.control.Slider
        IntensitySpinnerLED_4          matlab.ui.control.Spinner
        LEDDropDownLED_4               matlab.ui.control.DropDown
        LED4Label                      matlab.ui.control.Label
        Chained_LED_4                  matlab.ui.control.DropDown
        DropDown5Label                 matlab.ui.control.Label
        StopButtonLED                  matlab.ui.control.Button
        GoButtonLED                    matlab.ui.control.Button
        TimePausemsEditFieldLED_3      matlab.ui.control.NumericEditField
        TimePausemsEditFieldLED_2      matlab.ui.control.NumericEditField
        TimePausemsEditFieldLED_1      matlab.ui.control.NumericEditField
        TimeOffLabel                   matlab.ui.control.Label
        TimeOnmsEditFieldLED_3         matlab.ui.control.NumericEditField
        TimeOnmsEditFieldLED_2         matlab.ui.control.NumericEditField
        TimeOnmsEditFieldLED_1         matlab.ui.control.NumericEditField
        LEDpulsedurationLabel          matlab.ui.control.Label
        Chained_LED_3                  matlab.ui.control.DropDown
        DropDown4Label                 matlab.ui.control.Label
        IntensitySliderLED_3           matlab.ui.control.Slider
        IntensitySliderLED_2           matlab.ui.control.Slider
        IntensitySliderLED_1           matlab.ui.control.Slider
        Chained_LED_2                  matlab.ui.control.DropDown
        DropDown3Label                 matlab.ui.control.Label
        IntensitySpinnerLED_3          matlab.ui.control.Spinner
        IntensitySpinnerLED_2          matlab.ui.control.Spinner
        IntensitySpinnerLED_1          matlab.ui.control.Spinner
        IntensitySpinnerLabel          matlab.ui.control.Label
        Chained_LED_1                  matlab.ui.control.DropDown
        DropDown2Label                 matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        LEDDropDownLED_3               matlab.ui.control.DropDown
        LEDDropDownLED_2               matlab.ui.control.DropDown
        LEDDropDownLED_1               matlab.ui.control.DropDown
        SelectedLEDinSlotLabel         matlab.ui.control.Label
        ComplexLEDexcitationLabel      matlab.ui.control.Label
        LED3Label                      matlab.ui.control.Label
        LED2Label                      matlab.ui.control.Label
        LED1Label                      matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        SettingsTab                    matlab.ui.container.Tab
        GridLayout_3                   matlab.ui.container.GridLayout
        FilterWavelengthLED_8          matlab.ui.control.EditField
        FilterWavelengthLED_7          matlab.ui.control.EditField
        FilterWavelengthLED_6          matlab.ui.control.EditField
        FilterWavelengthLED_5          matlab.ui.control.EditField
        FilterWavelengthLED_4          matlab.ui.control.EditField
        FilterWavelengthLED_3          matlab.ui.control.EditField
        FilterWavelengthLED_2          matlab.ui.control.EditField
        FilterWavelengthLED_1          matlab.ui.control.EditField
        Filter                         matlab.ui.control.Label
        WavelengthLED_8                matlab.ui.control.NumericEditField
        WavelengthLED_7                matlab.ui.control.NumericEditField
        WavelengthLED_6                matlab.ui.control.NumericEditField
        WavelengthLED_5                matlab.ui.control.NumericEditField
        WavelengthLED_4                matlab.ui.control.NumericEditField
        WavelengthLED_3                matlab.ui.control.NumericEditField
        WavelengthLED_2                matlab.ui.control.NumericEditField
        WavelengthLED_1                matlab.ui.control.NumericEditField
        WavelengthnmLabel              matlab.ui.control.Label
        NameLabel                      matlab.ui.control.Label
        NameLEDEditField_8             matlab.ui.control.EditField
        NameLED8Label                  matlab.ui.control.Label
        NameLEDEditField_7             matlab.ui.control.EditField
        NameLED7Label                  matlab.ui.control.Label
        NameLEDEditField_6             matlab.ui.control.EditField
        NameLED6Label                  matlab.ui.control.Label
        NameLEDEditField_5             matlab.ui.control.EditField
        NameLED5Label                  matlab.ui.control.Label
        NameLEDEditField_4             matlab.ui.control.EditField
        NameLED4Label                  matlab.ui.control.Label
        NameLEDEditField_3             matlab.ui.control.EditField
        NameLED3Label                  matlab.ui.control.Label
        NameLEDEditField_2             matlab.ui.control.EditField
        NameLED2Label                  matlab.ui.control.Label
        NameLEDEditField_1             matlab.ui.control.EditField
        LED1Label_2                    matlab.ui.control.Label
        MaxCellSizeEditField           matlab.ui.control.NumericEditField
        MaxCellSizeEditFieldLabel      matlab.ui.control.Label
        GridLayout7                    matlab.ui.container.GridLayout
        EditFieldmarkerRangeMax        matlab.ui.control.NumericEditField
        EditFieldmarkerRangeMin        matlab.ui.control.NumericEditField
        MarkerRangeLabel               matlab.ui.control.Label
        ComPortEditField               matlab.ui.control.EditField
        ComPortEditFieldLabel          matlab.ui.control.Label
    end


    properties (Access = private)
        %%Base GUI properties%%
        hAx matlab.graphics.axis.Axes % Axes for animatedlines
        hAnimatedLine matlab.graphics.animation.AnimatedLine % animatedlines

        selectedRatioTab = 1;
        selectedLongTab = 1;
        selectedShortTab = 1;

        AxesLimits_Plot = repmat([0 1000 0 5],12,1); % Placeholder for axis size
        selectedList = 1;   %selected Axis for plots

        timedLED = false;   %command for Arduino
        comPort = 'COM7';   %COM port which is connected with arduino

        resolution = 34;    %distance between two individual points
        baudRate = 500000;  %speed for communication with arduino

        arduino = 0;            %the connected arduino
        stopMeasurement = false;    %Marker used to stop loop for listening for arduino messages

        analogValues;   %cell to save data from arduino
        measTime;  %cell to save time data
        measurementPlotData;    %cell to save plot data
        pwmChanged = false; % marker to check if pwm Values have been changed
        pwmChangedTag;
        LED1FRETActive = true; %active LEDs for FRET
        LED2FRETActive = false;
        LED3FRETActive = false;
        LED4FRETActive = false;
        LEDPulseActive = false;
        LEDs;

        mappingTableDropDowns = {
            'FRET_1', 'LEDDropDownFRET_1';
            'FRET_2', 'LEDDropDownFRET_2';
            'FRET_3', 'LEDDropDownFRET_3';
            'FRET_4', 'LEDDropDownFRET_4';
            'LED_1', 'LEDDropDownLED_1';
            'LED_2', 'LEDDropDownLED_2';
            'LED_3', 'LEDDropDownLED_3';
            'LED_4', 'LEDDropDownLED_4'
            };
    end

    methods (Access = private)

        %update the values XminXmaxYminYmax with Values from the properties
        function updateAxesfields(app,NumAxes)
            NumAxes = app.char2num(NumAxes);
            app.EditFieldXmin.Value = app.AxesLimits_Plot(NumAxes,1);
            app.EditFieldXmax.Value = app.AxesLimits_Plot(NumAxes,2);
            app.EditFieldYmin.Value = app.AxesLimits_Plot(NumAxes,3);
            app.EditFieldYmax.Value = app.AxesLimits_Plot(NumAxes,4);
        end

        %create plot areas
        function [hAx, hAnimatedLine]  = initializePlotAreas(app)
            hAx = createAxes(app);
            hAx = prepareAxes (app,hAx);
            hAnimatedLine = createAnimatedLines(app, hAx);
            transferAxes(app, hAx);
            %arrayfun(@(x) updateAxesLimits(app, x, [0 1000 0 0.00001]), 1:6)
                        for i = 1:4
                            app.selectedShortTab = i;
                            app.selectedLongTab = i;
                            app.selectedRatioTab = i;
                            updatePlotAxesLimits(app, 1)
                            updatePlotAxesLimits(app, 2)
                            updatePlotAxesLimits(app, 3)
                        end
            arrayfun(@(x) updateAxesfields(app, x),  1:12);
            transferAnimatedLines(app, hAnimatedLine);
        end


        %Update the plot axes limits from the app properties
        function updatePlotAxesLimits(app, plotNumber)
            try
                switch plotNumber
                    case 1
                        app.hAx(app.selectedShortTab).XLim = app.AxesLimits_Plot(plotNumber,1:2);
                        app.hAx(app.selectedShortTab).YLim = app.AxesLimits_Plot(plotNumber,3:4);
                    case 2
                        app.hAx(4 + app.selectedLongTab).XLim = app.AxesLimits_Plot(plotNumber,1:2);
                        app.hAx(4 + app.selectedLongTab).YLim = app.AxesLimits_Plot(plotNumber,3:4);
                    case 3
                        app.hAx(8 + app.selectedRatioTab).XLim = app.AxesLimits_Plot(plotNumber,1:2);
                        app.hAx(8 + app.selectedRatioTab).YLim = app.AxesLimits_Plot(plotNumber,3:4);
                end
            catch
                writeToConsole(app, "Couldn't set the limits!");
            end
        end

        %create new axes for plots
        function hAx = createAxes(app)
            hAx(1) = axes(app.LEDShortTab_1);
            hAx(2) = axes(app.LEDShortTab_2);
            hAx(3) = axes(app.LEDShortTab_3);
            hAx(4) = axes(app.LEDShortTab_4);
            hAx(5) = axes(app.LEDLongTab_1);
            hAx(6) = axes(app.LEDLongTab_2);
            hAx(7) = axes(app.LEDLongTab_3);
            hAx(8) = axes(app.LEDLongTab_4);
            hAx(9) = axes(app.LEDRatioTab_1);
            hAx(10) = axes(app.LEDRatioTab_2);
            hAx(11) = axes(app.LEDRatioTab_3);
            hAx(12) = axes(app.LEDRatioTab_4);
            writeToConsole(app, "Axes were created!");
        end

        %change properties of axes
        function hAx = prepareAxes(app,hAx)
            linkaxes(hAx);
            for i = 1:12
                hAx(i).Interactions = [zoomInteraction regionZoomInteraction rulerPanInteraction];
                hAx(i).XAxis.Label.String = "Time (s)";
            end
            for i = 1:4
                hAx(i).Title.String = "Shortwave emission";
            end
            for i = 5:8
                hAx(i).Title.String = "Longwave emission";
            end
            for i = 9:12
                hAx(i).Title.String = "Ratio";
            end
            for i = 1:8
                hAx(i).YAxis.Label.String = "Intensity (V)";
            end
            for i = 9:12
                hAx(i).YAxis.Label.String = "Ratio";
            end


            %   hAx(1).Toolbar = [];
            setappdata(hAx(1),'LegendColorbarManualSpace',1);
            setappdata(hAx(1),'LegendColorbarReclaimSpace',1);
            linkaxes(hAx, 'off');
            writeToConsole(app, "Axes were prepared!");
        end

        %transfer axes to app properties
        function transferAxes(app, hAx)
            for i = 1:12
                app.hAx(i) = hAx(i);
            end
            writeToConsole(app, "Axes were copied to app!");
        end

        %create animated lines later used as app property
        function hAnimatedLine = createAnimatedLines(app, hAx)
            for i = 1:4
                hAnimatedLine(i) = animatedline(hAx(i),'MaximumNumPoints',1e8,'Color','b');
            end
            for i = 5:8
                hAnimatedLine(i) = animatedline(hAx(i),'MaximumNumPoints',1e8,'Color','g');
            end
            for i = 9:12
                hAnimatedLine(i) = animatedline(hAx(i),'MaximumNumPoints',1e8);
            end
            writeToConsole(app, "Animated lines were created!");
        end

        %transfer animated lines the app properties
        function transferAnimatedLines(app, hAnimatedLine)
            for i = 1:12
                app.hAnimatedLine(i) = hAnimatedLine(i);
            end
            writeToConsole(app, "AnimatedLines were copied to app!");
        end

        %changes the color of the lamp
        function switchStatusLamp(app, color)
            app.Console.BackgroundColor = color;
        end

        %writes a message to the console field
        function writeToConsole(app, message)
            if isstring(message) || ischar(message)
                app.Console.Value = message;
            else
                app.Console.Value = "Message is not a string!";
            end
        end

        %read data send from arduino to serial buffer
        %get data in readable format and fill animated line
        function listenToArduino(app, numLEDs)

            switchStatusLamp(app, 'yellow');
            k = 1;
            for i = 1:numLEDs
                app.measTime{i} = zeros(app.MaxCellSizeEditField.Value,6);
                app.analogValues{i} = cell(app.MaxCellSizeEditField.Value,5);
            end
            while app.stopMeasurement == false
                cont = false;
                for i = 1:numLEDs
                    time = read(app.arduino, 10, "uint8");
                    analogReads = typecast(uint8([time(9) time(10)]), "uint16") ;
                    app.measTime{i}(k,1) = typecast(uint8([time(1) time(2) time(3) time(4)]), "uint32");
                    app.measTime{i}(k,2) = typecast(uint8([time(    5) time(6) time(7) time(8)]), "uint32");
                    app.measTime{i}(k,5) = analogReads + 1;

                    analog = read(app.arduino,4*analogReads-1, "uint8");
                    analogI = uint8(analog(1:analogReads*2));
                    analogI =  swapbytes(typecast(analogI, "uint16"));

                    analogII = uint8(analog(analogReads*2+1: analogReads*4-1));

                    if(analogII(end) > 3)
                        analogII(analogReads*2) = NaN;
                        analogII =  swapbytes(typecast(analogII, "uint16"));
                        analogII(analogReads) = NaN;
                        app.analogValues{i}{k,1} = analogI';
                        app.analogValues{i}{k,2}= analogII';
                        app.measTime{1}(k,6) = 1;
                        cont = true;
                        continue
                    end

                    analogII(analogReads*2) = read(app.arduino,1, "uint8");
                    analogII =  swapbytes(typecast(analogII, "uint16"));
                    app.analogValues{i}{k,1} = analogI';
                    app.analogValues{i}{k,2} = analogII';
                    app.analogValues{i}{k,3} = app.meas2mean(analogI,app.EditFieldmarkerRangeMin.Value, app.EditFieldmarkerRangeMax.Value);
                    app.analogValues{i}{k,4} = app.meas2mean(analogII,app.EditFieldmarkerRangeMin.Value, app.EditFieldmarkerRangeMax.Value);
                    app.analogValues{i}{k,5} = app.analogValues{i}{k,4} / app.analogValues{i}{k,3};
                end

                for i = 1:numLEDs
                    if cont == false
                        timeMeas = (app.measTime{i}(k,1) - app.measTime{i}(1,1))/1000000;
                        addpoints(app.hAnimatedLine(0+i), timeMeas, (((5 * app.analogValues{i}{k,3})/1023)*2));
                        addpoints(app.hAnimatedLine(4+i), timeMeas, (((5 * app.analogValues{i}{k,4})/1023)*2));
                        addpoints(app.hAnimatedLine(8+i), timeMeas, app.analogValues{i}{k,5});
                    end
                end

                if mod(k,20)==1 && cont == false
                    for i = 1:numLEDs

                        try
                            app.TimeEditField.Value = timeMeas;
                            displayMeans(app, app.analogValues{i}{k,3},app.analogValues{i}{k,4},app.analogValues{i}{k,5}, i)
                                                    end
                    end
                    drawnow limitrate;
                end

                if  app.pwmChanged == true
                    changePWM(app);
                end

                k = k+1;
            end
            write(app.arduino, 0, "uint16");
            flushArduinoMessages(app);
            readArduinoMessage(app);
            app.stopMeasurement = false;
            calculateExtraTimeData(app, k, numLEDs);
            for i = 1:12
                [app.measurementPlotData(i).time, app.measurementPlotData(i).int] = getpoints(app.hAnimatedLine(i));
            end
            switchStatusLamp(app, 'green');

        end

        %start serial connecntion with arduino
        function connectToArduino(app)

            app.arduino = serialport(app.comPort,app.baudRate);
            pause(0.5);
            configureTerminator(app.arduino,"CR/LF", 10);
            flushArduinoMessages(app);
            writeToConsole(app, "Connected to Arduino");
        end

        function readArduinoMessage(app)
            pause(0.4);
            if app.arduino.NumBytesAvailable > 0
                %To many bytes avaible, doesnt read fast enough... maybe
                %more pause??
                writeToConsole(app, append("Arduino: ", readline(app.arduino)));
            end
        end

        function flushArduinoMessages(app)
            flush(app.arduino);
        end

        %send data to arduino to start selected measurement
        function startMeasurement(app, mode, singleAfter)
            arguments
                app
                mode
                singleAfter = false;
            end
            switch mode
                case "FRET"
                    write(app.arduino, 1, "uint8");
                    pause(0.1);
                    readArduinoMessage(app);
                    pause(0.1);
                    numLEDs = sum([app.LEDSwitch_1.Value, app.LEDSwitch_2.Value, app.LEDSwitch_3.Value, app.LEDSwitch_4.Value]);
                    write(app.arduino, numLEDs, "uint8");
                    for i = 1:numLEDs
                        write(app.arduino, app.char2num(app.(append("LEDDropDownFRET_",num2str(i))).Value) -1, "uint8");
                        write(app.arduino, app.(append("IntensitySpinnerFRET_",num2str(i))).Value, "uint8");
                        write(app.arduino, app.convertUnit2Micro(app.(append("TimeOnmsEditFieldFRET_",num2str(i))).Value,"milli"), "uint32");
                        pause(0.1)
                    end
                    write(app.arduino, app.convertUnit2Micro(app.TimePausemsEditFieldFRET.Value,"milli"), "uint32");
                    pause(0.1)
                    readArduinoMessage(app);

                    pause(0.1)
                    listenToArduino(app,numLEDs);
                    flush(app.arduino);

                case "LED"
                    write(app.arduino, 2, "uint8");
                    readArduinoMessage(app);
                    numLEDs = 1;
                    write(app.arduino, numLEDs, "uint8");
                    if singleAfter == false
                        write(app.arduino, app.char2num(app.(append("LEDDropDownLED_", num2str(app.LEDforSingleLEDDropDown.Value))).Value) - 1, "uint8");
                    end
                    if singleAfter == true
                        write(app.arduino, app.char2num(app.(append("LEDDropDownLED_", num2str(app.LEDforAfterComplexLEDDropDown.Value))).Value) - 1, "uint8");
                    end

                    write(app.arduino, app.char2num(app.(append("IntensitySpinnerLED_", num2str(app.LEDforSingleLEDDropDown.Value))).Value) - 1, "uint8");
                    readArduinoMessage(app);
                    lightIdle(app);
                    flush(app.arduino);

                case "Complex"
                    write(app.arduino, 3, "uint8");
                    pause(0.1);
                    readArduinoMessage(app);
                    pause(0.1);
                    numLEDs = sum([app.char2num(app.Chained_LED_1.Value) ~= 0, app.char2num(app.Chained_LED_2.Value) ~= 0, app.char2num(app.Chained_LED_3.Value) ~= 0, app.char2num(app.Chained_LED_4.Value) ~= 0]);
                    write(app.arduino, numLEDs, "uint8");
                    for i = 1:numLEDs
                        led = num2str(app.(append("Chained_LED_",num2str(i))).Value);
                        write(app.arduino, app.char2num(app.(append("LEDDropDownLED_",led)).Value) - 1 , "uint8");
                        write(app.arduino, app.(append("IntensitySpinnerLED_",led)).Value, "uint8");
                        write(app.arduino, app.convertUnit2Micro(app.(append("TimeOnmsEditFieldLED_",led)).Value,"milli"), "uint32");
                        write(app.arduino, app.convertUnit2Micro(app.(append("TimePausemsEditFieldLED_",led)).Value,"milli"), "uint32");
                        pause(0.1)
                    end
                    write(app.arduino, app.convertUnit2Micro(app.TimePauseAllmsEditFieldLED.Value,"milli"), "uint32");
                    write(app.arduino, app.RepeatsSpinner.Value, "uint8");
                    readArduinoMessage(app);
                    readArduinoMessage(app);
                    if app.RepeatsSpinner.Value == 0
                        lightIdle(app);
                    else
                        switchStatusLamp(app, 'yellow');
                        while(app.arduino.NumBytesAvailable == 0)
                            pause(0.5);
                            if  app.pwmChanged == true
                                changePWM(app);
                            end
                            if app.stopMeasurement == true
                                write(app.arduino, 0, "uint16");
                                app.stopMeasurement = false;
                                pause(0.3);
                            end
                        end
                        readArduinoMessage(app);
                        switchStatusLamp(app, 'green');
                    end

                    if app.SwitchLEDonafterComplexCheckBox.Value == 1
                        startMeasurement(app, "LED", true);
                        app.GoButton.Enable = false;
                        app.GoButtonLED.Enable = false;
                        app.StartComplexButton_2.Enable = false;
                        app.pwmChanged = false;
                    end

                    flush(app.arduino);

                case "FRETwPulse"
                    write(app.arduino, 4, "uint8");
                    pause(0.1);
                    readArduinoMessage(app);
                    pause(0.1);
                    numLEDs = sum([app.LEDSwitch_1.Value, app.LEDSwitch_2.Value, app.LEDSwitch_3.Value, app.LEDSwitch_4.Value]);
                    write(app.arduino, numLEDs, "uint8");
                    for i = 1:numLEDs
                        write(app.arduino, app.char2num(app.(append("LEDDropDownFRET_",num2str(i))).Value) -1, "uint8");
                        write(app.arduino, app.(append("IntensitySpinnerFRET_",num2str(i))).Value, "uint8");
                        write(app.arduino, app.convertUnit2Micro(app.(append("TimeOnmsEditFieldFRET_",num2str(i))).Value,"milli"), "uint32");
                        pause(0.1)
                    end
                    write(app.arduino, app.convertUnit2Micro(app.TimePausemsEditFieldFRET.Value,"milli"), "uint32");
                    pause(0.1)
                        write(app.arduino, app.char2num(app.LEDDropDownPulse.Value) -1, "uint8");
                        write(app.arduino, app.IntensitySpinnerPulse.Value, "uint8");
                        write(app.arduino, app.EditFieldTimeDur.Value, "uint32");
                        write(app.arduino, app.EditFieldTimePulse.Value * 1000, "uint32");
                        pause(0.1)
               readArduinoMessage(app);
                    pause(0.1)
                    listenToArduino(app,numLEDs);
                    flush(app.arduino);


                otherwise
                    writeToConsole(app, "Warning unexpected mode")
            end
            app.GoButton.Enable = true;
            app.GoButtonLED.Enable = true;
            app.StartComplexButton_2.Enable = true;
        end

        %Calculate some extra data for the time
        function calculateExtraTimeData(app, k, numLEDs)
            for h = 1:numLEDs
                app.measTime{h}(:,3) =app.measTime{h}(:,2) - app.measTime{h}(:,1);
                j = 2;
                for i = 1:k
                    app.measTime{h}(j,4) = app.measTime{h}(j,1) - app.measTime{h}(i,2);
                    j = j+ 1;
                end
            end
        end

        %Send data to corresponding editfields
        function displayMeans(app, meanKurz, meanLang, meanRatio, numLED)
            app.(['MeanKurzwellig_' num2str(numLED)]).Value = (((5 * meanKurz)/1023)*2);
            app.(['MeanLangwellig_' num2str(numLED)]).Value = (((5 * meanLang)/1023)*2);
            app.(['MeanRatio_' num2str(numLED)]).Value = meanRatio;
        end

        %Send the last changed PWM value to the arduino
        function changePWM(app)
            [~,idx] = ismember(app.pwmChangedTag,app.mappingTableDropDowns(:,1));
            wantedLED = app.mappingTableDropDowns{idx,2};

            write(app.arduino, app.char2num(app.(wantedLED).Value) - 1, "uint8");
            write(app.arduino, app.(append("IntensitySpinner",app.pwmChangedTag)).Value, "uint8");
            writeToConsole(app, append("PWM changed to ",num2str( app.(append("IntensitySpinner",app.pwmChangedTag)).Value)));
            app.pwmChanged = false;
        end

        function lightIdle(app)
            switchStatusLamp(app, 'yellow');
            %    writeToConsole(app, "light idle");
            while app.stopMeasurement == false
                pause(0.5);
                if  app.pwmChanged == true
                    changePWM(app);
                end
            end
            write(app.arduino, 0, "uint16");
            app.stopMeasurement = false;
            pause(0.3);
            readArduinoMessage(app);
            switchStatusLamp(app, 'green');
        end


        function clearAnimatedLines(app)
            for i = 1:12
                clearpoints(app.hAnimatedLine(i));
            end
        end

        function changeLEDdropdownNames(app)
            app.LEDDropDownFRET_1.Items = {app.LEDs.name};
            app.LEDDropDownFRET_2.Items = {app.LEDs.name};
            app.LEDDropDownFRET_3.Items = {app.LEDs.name};
            app.LEDDropDownFRET_4.Items = {app.LEDs.name};
            app.LEDDropDownPulse.Items = {app.LEDs.name};
            app.LEDDropDownLED_1.Items =  {app.LEDs.name};
            app.LEDDropDownLED_2.Items =  {app.LEDs.name};
            app.LEDDropDownLED_3.Items =  {app.LEDs.name};
            app.LEDDropDownLED_4.Items =  {app.LEDs.name};
        end

        function updateLEDNames(app)
            for i = 1:8
                app.LEDs(i).name = app.(append("NameLEDEditField_", num2str(i))).Value;
            end
            changeLEDdropdownNames(app);
        end

        function updateLEDWaveLengths(app)
            for i = 1:8
                app.LEDs(i).wavelength = app.(append("WavelengthLED_", num2str(i))).Value;
            end
        end

        function loadLEDData(app, appDir)

            try
                data = readtable(fullfile(appDir, 'LED_list.csv'),'NumHeaderLines',0);
            catch
                data = readtable('LED_list.csv','NumHeaderLines',0);
            end

            for i = 1: length(data.LED_)
                app.(append("NameLEDEditField_", num2str(i))).Value = data.Name{i};
                app.(append("WavelengthLED_", num2str(i))).Value = data.Peak(i);
                app.(append("FilterWavelengthLED_", num2str(i))).Value = append(data.Filter{i}, ": ", data.Wavelength{i});
            end
        end
        
        function loadSettings(app,appDir)
            try
                data = readtable(fullfile(appDir, 'Settings.csv'),'ReadVariableNames',0, 'Format','auto');
            catch
                data = readtable('Settings.csv','ReadVariableNames',0, 'Format','auto');
            end
            app.ComPortEditField.Value = data.Var2{1};
            app.comPort = app.ComPortEditField.Value;
            app.EditFieldmarkerRangeMin.Value = app.char2num(data.Var2{2});
            app.EditFieldmarkerRangeMax.Value = data.Var3(2);
            app.MaxCellSizeEditField.Value = app.char2num(data.Var2{3});
        end
    end

    methods(Static)

        %Convert a char to numeric
        function num = char2num(char)
            if ischar(char)
                char = str2double(char);
            end
            num = char;
        end

        % calculate the mean of a measurement array based on two markers
        function measMean = meas2mean(measurement, markerStart, markerEnd)
            measMean = mean(measurement(markerStart:markerEnd));
        end

        % Save all relevant variables as .mat file
        function saveVariables(measurementPlotData, analogValues, measTime)
            uisave({'measurementPlotData', 'analogValues', 'measTime'}, string(datetime('now','Format','dd.MM.y-HH_mm_ss')));
        end

        function value = convertUnit2Micro(value, unit)
            arguments
                value double
                unit string = ""
            end
            switch unit
                case "milli"
                    value = value * 1000;
                case ""
                    value = value * 1000000;
            end
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            initializePlotAreas(app);
            appDir = sprintf('%s\\Documents\\EnhancedLEDControl', getenv('PUBLIC'));      
            loadLEDData(app, appDir);
            updateLEDNames(app);
            updateLEDWaveLengths(app);
            loadSettings(app, appDir);
            Chained_LEDValueChanged(app)
            app.LEDSwitch_1.Value = 1;
            app.selectedShortTab = 1;
            app.selectedLongTab = 1;
            app.selectedRatioTab = 1;
            writeToConsole(app, "Ready!");
        end

        % Value changed function: IntensitySliderFRET_1, 
        % ...and 7 other components
        function IntensitySliderFRETValueChanged(app, event)
            value = event.Value;
            app.(append('IntensitySlider', event.Source.Tag)).Value  = round(value);
            app.(append('IntensitySpinner', event.Source.Tag)).Value  = round(value);
            app.pwmChanged = true;
            app.pwmChangedTag = event.Source.Tag;

        end

        % Value changed function: IntensitySpinnerFRET_1, 
        % ...and 7 other components
        function IntensitySpinnerFRETValueChanged(app, event)
            value = event.Value;
            app.(append('IntensitySlider', event.Source.Tag)).Value  = round(value);
            app.pwmChanged = true;
            app.pwmChangedTag = event.Source.Tag;

        end

        % Value changed function: FrequencyEditFieldFRET
        function FrequencyEditFieldFRETValueChanged(app, event)
            frequency = event.Value;

            selLEDs =  [app.LEDSwitch_1.Value app.LEDSwitch_2.Value app.LEDSwitch_3.Value app.LEDSwitch_4.Value];
            timeValues = [app.TimeOnmsEditFieldFRET_1.Value app.TimeOnmsEditFieldFRET_2.Value app.TimeOnmsEditFieldFRET_3.Value app.TimeOnmsEditFieldFRET_4.Value];
            pause  = 1000/frequency - sum(selLEDs .* timeValues);

            app.TimePausemsEditFieldFRET.Value = pause;
        end

        % Value changed function: EditFieldXmin
        function EditFieldXminValueChanged(app, event)
            value = app.EditFieldXmin.Value;
            app.AxesLimits_Plot(app.selectedList,1) = value;
            updatePlotAxesLimits(app, app.selectedList);
        end

        % Value changed function: EditFieldXmax
        function EditFieldXmaxValueChanged(app, event)
            value = app.EditFieldXmax.Value;
            app.AxesLimits_Plot(app.selectedList,2) = value;
            updatePlotAxesLimits(app, app.selectedList);
        end

        % Value changed function: EditFieldYmin
        function EditFieldYminValueChanged(app, event)
            value = app.EditFieldYmin.Value;
            app.AxesLimits_Plot(app.selectedList,3) = value;
            updatePlotAxesLimits(app, app.selectedList);
        end

        % Value changed function: EditFieldYmax
        function EditFieldYmaxValueChanged(app, event)
            value = app.EditFieldYmax.Value;
            app.AxesLimits_Plot(app.selectedList,4) = value;
            updatePlotAxesLimits(app, app.selectedList);
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.char2num(app.ListBox.Value);
            app.selectedList = value;
            updateAxesfields(app, value);
        end

        % Value changed function: ComPortEditField
        function ComPortEditFieldValueChanged(app, event)
            value = app.ComPortEditField.Value;
            app.comPort = value;
        end

        % Value changed function: TimeOnmsEditFieldFRET_1, 
        % ...and 4 other components
        function TimePausemsEditFieldFRETValueChanged(app, event)
            value = app.TimePausemsEditFieldFRET.Value;
            selLEDs =  [app.LEDSwitch_1.Value app.LEDSwitch_2.Value app.LEDSwitch_3.Value app.LEDSwitch_4.Value];
            timeValues = [app.TimeOnmsEditFieldFRET_1.Value app.TimeOnmsEditFieldFRET_2.Value app.TimeOnmsEditFieldFRET_3.Value app.TimeOnmsEditFieldFRET_4.Value];
            frequency = 1000 /(value + sum(selLEDs .* timeValues));
            app.('FrequencyEditFieldFRET').Value = frequency;
        end

        % Button pushed function: GoButton, GoButtonLED, 
        % ...and 1 other component
        function GoButtonPushed(app, event)
            try
                app.GoButton.Enable = false;
                app.StartComplexButton_2.Enable = false;
                app.GoButtonLED.Enable = false;

                app.pwmChanged = false;
                tag = event.Source.Tag;
                                    if app.LEDPulseActive == true && tag == "FRET"
                                        tag = "FRETwPulse";
                                    end

                if class(app.arduino) ~= "internal.Serialport"
                    connectToArduino(app);
                    pause(1);
                end
                flush(app.arduino);
                    readArduinoMessage(app);
                if app.arduino.NumBytesAvailable > 0
                    writeToConsole(app, readline(app.arduino));
                end
                startMeasurement(app,tag);
                app.GoButton.Enable = true;
                app.GoButtonLED.Enable = true;
                app.StartComplexButton_2.Enable = true;

            catch ME
                app.writeToConsole(ME.message);
                app.GoButton.Enable = true;
                app.GoButtonLED.Enable = true;
                app.StartComplexButton_2.Enable = true;
                try
                    flush(app.arduino);
                end
            end

        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            app.saveVariables(app.measurementPlotData, app.analogValues, app.measTime);
            writeToConsole(app, "Data saved!");
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            wb = waitbar(0, 'Loading Data ...','WindowStyle', 'modal');
            wbch = allchild(wb);
            jp = wbch(1).JavaPeer;
            jp.setIndeterminate(1);
            uiopen('*.mat');
            if ~isempty(measurementPlotData)
                app.measurementPlotData = measurementPlotData;
                app.analogValues = analogValues;
                app.measTime = measTime;
                for i = 1:12
                    addpoints(app.hAnimatedLine(i), app.measurementPlotData(i).time, app.measurementPlotData(i).int)
                    drawnow limitrate
                end
                writeToConsole(app, "Data loaded!");
            else
                writeToConsole(app, "Data is empty - not loaded!");
            end
            close(wb);
        end

        % Button pushed function: StopButton, StopButtonLED
        function StopButtonPushed(app, event)
            app.stopMeasurement = true;
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            msg = 'Do you really want to open a new measurement. All unsaved progress will be lost!';
title = 'Confirm New Measurement';
selection = uiconfirm(app.EnhancedLEDControlUIFigure,msg,title);
            if selection == "Cancel" 
            return 
            end

            clearAnimatedLines(app);
            app.analogValues = [];
            app.measTime = [];
            app.arduino = 0;
            app.stopMeasurement = false;
            app.pwmChanged = false;
            writeToConsole(app, "New Measurement!")
        end

        % Value changed function: LEDSwitchPulse, LEDSwitch_2, 
        % ...and 2 other components
        function LEDSwitch_ValueChanged(app, event)
            switchName = event.Source.Tag; % get the name of the switch that was changed
            switchValue = event.Value; % get the value of the switch (1 for on, 0 for off)

            % use a switch statement to determine which switch was changed
            switch switchName
                case 'FRET_2'
                    lamp = app.LEDLamp_2;
                    app.LED2FRETActive = switchValue;
                case 'FRET_3'
                    lamp = app.LEDLamp_3;
                    app.LED3FRETActive = switchValue;
                case 'FRET_4'
                    lamp = app.LEDLamp_4;
                    app.LED4FRETActive = switchValue;
                    % add additional cases for more switches if needed
                case 'Pulse'
                    lamp = app.LEDLampPulse;
                    app.LEDPulseActive = switchValue;
            end

            % set the lamp color based on the switch value
            if switchValue == 1
                lamp.Color = [0 1 0]; % green
            else
                lamp.Color = [0.90 0.90 0.90]; % white (off)
            end

        end

        % Value changed function: Chained_LED_1, Chained_LED_2, 
        % ...and 16 other components
        function Chained_LEDValueChanged(app, event)

            x = zeros(1,4);
            y = zeros(1,4);
            w = zeros(1,4);

            for i=1:4
                LEDnum = app.(append("Chained_LED_", num2str(i))).Value;
                if LEDnum ~= '0'
                    x(i) = app.(append("TimeOnmsEditFieldLED_", LEDnum)).Value;
                    y(i) = app.(append("TimePausemsEditFieldLED_", LEDnum)).Value;
                    w(i) = app.LEDs(app.char2num(app.(append("LEDDropDownLED_", LEDnum)).Value)).wavelength;
                end
            end
            vec = [repelem(w(1),x(1),1); repelem(0, y(1),1);
                repelem(w(2),x(2),1); repelem(0, y(2),1);
                repelem(w(3),x(3),1); repelem(0, y(3),1);
                repelem(w(4),x(4),1); repelem(0, y(4),1);
                repelem(0, app.TimePauseAllmsEditFieldLED.Value,1)];
            repeats = app.RepeatsSpinner.Value;
            if repeats == 0
                repeats = 100;
            end
            repvec = [0; 0; repmat(vec,repeats,1); 0; 0;];
            repvec(:,2) = (1:size(repvec(:,1),1))/1000;

            diff_data = diff(repvec(:,1));
            change_indices = find(diff_data ~= 0);
            expanded_vec = [change_indices; change_indices+1; change_indices-1];
            expanded_vec = sort(expanded_vec);
            short_data = repvec(expanded_vec,:);
            plot(app.UIAxes,short_data(:,2), short_data(:,1));


        end

        % Value changed function: WavelengthLED_1, WavelengthLED_2, 
        % ...and 6 other components
        function WavelengthLEDValueChanged(app, event)
            updateLEDWaveLengths(app);
        end

        % Value changed function: NameLEDEditField_1, NameLEDEditField_2, 
        % ...and 6 other components
        function NameLEDEditFieldValueChanged(app, event)
            updateLEDNames(app);
        end

        % Close request function: EnhancedLEDControlUIFigure
        function EnhancedLEDControlUIFigureCloseRequest(app, event)
            delete(app)
            write(app.arduino, 0, "uint16");
        end

        % Button down function: LEDRatioTab_1, LEDRatioTab_2, 
        % ...and 2 other components
        function LEDRatioTab_ButtonDown(app, event)
            tag = event.Source.Tag;
            app.selectedRatioTab = app.char2num(tag);
        end

        % Button down function: LEDLongTab_1, LEDLongTab_2, LEDLongTab_3, 
        % ...and 1 other component
        function LEDLongTab_ButtonDown(app, event)
            tag = event.Source.Tag;
            app.selectedLongTab = app.char2num(tag);
        end

        % Button down function: LEDShortTab_1, LEDShortTab_2, 
        % ...and 2 other components
        function LEDShortTab_ButtonDown(app, event)
            tag = event.Source.Tag;
            app.selectedShortTab = app.char2num(tag);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.EnhancedLEDControlUIFigure = uifigure('Visible', 'off');
            app.EnhancedLEDControlUIFigure.Name = 'Enhanced LEDControl';
            app.EnhancedLEDControlUIFigure.Icon = fullfile(pathToMLAPP, 'EnhancedLEDControlSplash.png');
            app.EnhancedLEDControlUIFigure.CloseRequestFcn = createCallbackFcn(app, @EnhancedLEDControlUIFigureCloseRequest, true);
            app.Menu = uimenu(app.EnhancedLEDControlUIFigure);
            app.Menu.Text = 'Menu';
            app.NewMenu = uimenu(app.Menu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Text = 'New';
            app.LoadMenu = uimenu(app.Menu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.SaveMenu = uimenu(app.Menu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.TabGroup = uitabgroup(app.EnhancedLEDControlUIFigure);
            app.FRETTab = uitab(app.TabGroup);
            app.FRETTab.Title = 'FRET';
            app.GridLayout = uigridlayout(app.FRETTab);
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.MeansshortwaveemissionLabel = uilabel(app.GridLayout5);
            app.MeansshortwaveemissionLabel.Text = 'Means shortwave emission';
            app.MeanslongwaveemissionLabel = uilabel(app.GridLayout5);
            app.MeanslongwaveemissionLabel.Text = 'Means longwave emission';
            app.MeanRatioLabel = uilabel(app.GridLayout5);
            app.MeanRatioLabel.Text = 'Mean Ratio';
            app.GridLayout8 = uigridlayout(app.GridLayout5);
            app.MeanKurzwellig_1 = uieditfield(app.GridLayout8, 'numeric');
            app.MeanKurzwellig_1.ValueDisplayFormat = '%.3f';
            app.MeanKurzwellig_1.Editable = 'off';
            app.MeanKurzwellig_2 = uieditfield(app.GridLayout8, 'numeric');
            app.MeanKurzwellig_2.ValueDisplayFormat = '%.3f';
            app.MeanKurzwellig_2.Editable = 'off';
            app.MeanKurzwellig_3 = uieditfield(app.GridLayout8, 'numeric');
            app.MeanKurzwellig_3.ValueDisplayFormat = '%.3f';
            app.MeanKurzwellig_3.Editable = 'off';
            app.MeanKurzwellig_4 = uieditfield(app.GridLayout8, 'numeric');
            app.MeanKurzwellig_4.ValueDisplayFormat = '%.3f';
            app.MeanKurzwellig_4.Editable = 'off';
            app.GridLayout8_2 = uigridlayout(app.GridLayout5);
            app.MeanLangwellig_1 = uieditfield(app.GridLayout8_2, 'numeric');
            app.MeanLangwellig_1.ValueDisplayFormat = '%.3f';
            app.MeanLangwellig_1.Editable = 'off';
            app.MeanLangwellig_2 = uieditfield(app.GridLayout8_2, 'numeric');
            app.MeanLangwellig_2.ValueDisplayFormat = '%.3f';
            app.MeanLangwellig_2.Editable = 'off';
            app.MeanLangwellig_3 = uieditfield(app.GridLayout8_2, 'numeric');
            app.MeanLangwellig_3.ValueDisplayFormat = '%.3f';
            app.MeanLangwellig_3.Editable = 'off';
            app.MeanLangwellig_4 = uieditfield(app.GridLayout8_2, 'numeric');
            app.MeanLangwellig_4.ValueDisplayFormat = '%.3f';
            app.MeanLangwellig_4.Editable = 'off';
            app.GridLayout8_3 = uigridlayout(app.GridLayout5);
            app.MeanRatio_1 = uieditfield(app.GridLayout8_3, 'numeric');
            app.MeanRatio_1.ValueDisplayFormat = '%.3f';
            app.MeanRatio_1.Editable = 'off';
            app.MeanRatio_2 = uieditfield(app.GridLayout8_3, 'numeric');
            app.MeanRatio_2.ValueDisplayFormat = '%.3f';
            app.MeanRatio_2.Editable = 'off';
            app.MeanRatio_3 = uieditfield(app.GridLayout8_3, 'numeric');
            app.MeanRatio_3.ValueDisplayFormat = '%.3f';
            app.MeanRatio_3.Editable = 'off';
            app.MeanRatio_4 = uieditfield(app.GridLayout8_3, 'numeric');
            app.MeanRatio_4.ValueDisplayFormat = '%.3f';
            app.MeanRatio_4.Editable = 'off';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.XminLabel = uilabel(app.GridLayout4);
            app.XminLabel.Text = 'Xmin';
            app.XmaxLabel = uilabel(app.GridLayout4);
            app.XmaxLabel.Text = 'Xmax';
            app.EditFieldXmin = uieditfield(app.GridLayout4, 'numeric');
            app.EditFieldXmin.ValueChangedFcn = createCallbackFcn(app, @EditFieldXminValueChanged, true);
            app.EditFieldXmax = uieditfield(app.GridLayout4, 'numeric');
            app.EditFieldXmax.ValueChangedFcn = createCallbackFcn(app, @EditFieldXmaxValueChanged, true);
            app.YminLabel = uilabel(app.GridLayout4);
            app.YminLabel.Text = 'Ymin';
            app.YmaxLabel = uilabel(app.GridLayout4);
            app.YmaxLabel.Text = 'Ymax';
            app.EditFieldYmin = uieditfield(app.GridLayout4, 'numeric');
            app.EditFieldYmin.ValueChangedFcn = createCallbackFcn(app, @EditFieldYminValueChanged, true);
            app.EditFieldYmax = uieditfield(app.GridLayout4, 'numeric');
            app.EditFieldYmax.ValueChangedFcn = createCallbackFcn(app, @EditFieldYmaxValueChanged, true);
            app.StopButton = uibutton(app.GridLayout2, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.GoButton = uibutton(app.GridLayout2, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Tag = 'FRET';
            app.GoButton.Text = 'Go';
            app.ListBox = uilistbox(app.GridLayout2);
            app.ListBox.Items = {'Kurzwellig', 'Langwellig', 'Ratio'};
            app.ListBox.ItemsData = {'1', '2', '3'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = '1';
            app.TimeEditFieldLabel = uilabel(app.GridLayout2);
            app.TimeEditFieldLabel.Text = 'Time:';
            app.TimeEditField = uieditfield(app.GridLayout2, 'numeric');
            app.TimeEditField.ValueDisplayFormat = '%.1f';
            app.PlotGroup_1 = uitabgroup(app.GridLayout);
            app.PlotGroup_1.Tag = '1';
            app.LEDShortTab_1 = uitab(app.PlotGroup_1);
            app.LEDShortTab_1.Title = 'LED1';
            app.LEDShortTab_1.ButtonDownFcn = createCallbackFcn(app, @LEDShortTab_ButtonDown, true);
            app.LEDShortTab_1.Tag = '1';
            app.LEDShortTab_2 = uitab(app.PlotGroup_1);
            app.LEDShortTab_2.Title = 'LED2';
            app.LEDShortTab_2.ButtonDownFcn = createCallbackFcn(app, @LEDShortTab_ButtonDown, true);
            app.LEDShortTab_2.Tag = '2';
            app.LEDShortTab_3 = uitab(app.PlotGroup_1);
            app.LEDShortTab_3.Title = 'LED3';
            app.LEDShortTab_3.ButtonDownFcn = createCallbackFcn(app, @LEDShortTab_ButtonDown, true);
            app.LEDShortTab_3.Tag = '3';
            app.LEDShortTab_4 = uitab(app.PlotGroup_1);
            app.LEDShortTab_4.Title = 'LED4';
            app.LEDShortTab_4.ButtonDownFcn = createCallbackFcn(app, @LEDShortTab_ButtonDown, true);
            app.LEDShortTab_4.Tag = '4';
            app.PlotGroup_2 = uitabgroup(app.GridLayout);
            app.PlotGroup_2.Tag = '2';
            app.LEDLongTab_1 = uitab(app.PlotGroup_2);
            app.LEDLongTab_1.Title = 'LED1';
            app.LEDLongTab_1.ButtonDownFcn = createCallbackFcn(app, @LEDLongTab_ButtonDown, true);
            app.LEDLongTab_1.Tag = '1';
            app.LEDLongTab_2 = uitab(app.PlotGroup_2);
            app.LEDLongTab_2.Title = 'LED2';
            app.LEDLongTab_2.ButtonDownFcn = createCallbackFcn(app, @LEDLongTab_ButtonDown, true);
            app.LEDLongTab_2.Tag = '2';
            app.LEDLongTab_3 = uitab(app.PlotGroup_2);
            app.LEDLongTab_3.Title = 'LED3';
            app.LEDLongTab_3.ButtonDownFcn = createCallbackFcn(app, @LEDLongTab_ButtonDown, true);
            app.LEDLongTab_3.Tag = '3';
            app.LEDLongTab_4 = uitab(app.PlotGroup_2);
            app.LEDLongTab_4.Title = 'LED4';
            app.LEDLongTab_4.ButtonDownFcn = createCallbackFcn(app, @LEDLongTab_ButtonDown, true);
            app.LEDLongTab_4.Tag = '4';
            app.PlotGroup_3 = uitabgroup(app.GridLayout);
            app.PlotGroup_3.Tag = '3';
            app.LEDRatioTab_1 = uitab(app.PlotGroup_3);
            app.LEDRatioTab_1.Title = 'Ratio1';
            app.LEDRatioTab_1.ButtonDownFcn = createCallbackFcn(app, @LEDRatioTab_ButtonDown, true);
            app.LEDRatioTab_1.Tag = '1';
            app.LEDRatioTab_2 = uitab(app.PlotGroup_3);
            app.LEDRatioTab_2.Title = 'Ratio2';
            app.LEDRatioTab_2.ButtonDownFcn = createCallbackFcn(app, @LEDRatioTab_ButtonDown, true);
            app.LEDRatioTab_2.Tag = '2';
            app.LEDRatioTab_3 = uitab(app.PlotGroup_3);
            app.LEDRatioTab_3.Title = 'Ratio3';
            app.LEDRatioTab_3.ButtonDownFcn = createCallbackFcn(app, @LEDRatioTab_ButtonDown, true);
            app.LEDRatioTab_3.Tag = '3';
            app.LEDRatioTab_4 = uitab(app.PlotGroup_3);
            app.LEDRatioTab_4.Title = 'Ratio4';
            app.LEDRatioTab_4.ButtonDownFcn = createCallbackFcn(app, @LEDRatioTab_ButtonDown, true);
            app.LEDRatioTab_4.Tag = '4';
            app.TabGroup2 = uitabgroup(app.GridLayout);
            app.LED1Tab = uitab(app.TabGroup2);
            app.LED1Tab.Title = 'LED1';
            app.LEDLabel_2 = uilabel(app.LED1Tab);
            app.LEDLabel_2.Text = 'Selected LED';
            app.LEDDropDownFRET_1 = uidropdown(app.LED1Tab);
            app.LEDDropDownFRET_1.Items = {'LED1', 'LED2', 'LED3', 'LED4', 'LED5', 'LED6', 'LED7', 'LED8'};
            app.LEDDropDownFRET_1.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.LEDDropDownFRET_1.Tag = 'FRET_1';
            app.LEDDropDownFRET_1.Value = '1';
            app.TimeOnEditField_2Label_2 = uilabel(app.LED1Tab);
            app.TimeOnEditField_2Label_2.Text = 'LED Pulse duration';
            app.TimeOnmsEditFieldFRET_1 = uieditfield(app.LED1Tab, 'numeric');
            app.TimeOnmsEditFieldFRET_1.Limits = [3 Inf];
            app.TimeOnmsEditFieldFRET_1.ValueDisplayFormat = '%11.4g ms';
            app.TimeOnmsEditFieldFRET_1.ValueChangedFcn = createCallbackFcn(app, @TimePausemsEditFieldFRETValueChanged, true);
            app.TimeOnmsEditFieldFRET_1.Tag = 'FRET_1';
            app.TimeOnmsEditFieldFRET_1.Value = 3;
            app.IntensitySpinner_2Label_2 = uilabel(app.LED1Tab);
            app.IntensitySpinner_2Label_2.Text = 'Intensity';
            app.IntensitySpinnerFRET_1 = uispinner(app.LED1Tab);
            app.IntensitySpinnerFRET_1.Limits = [1 255];
            app.IntensitySpinnerFRET_1.RoundFractionalValues = 'on';
            app.IntensitySpinnerFRET_1.ValueChangedFcn = createCallbackFcn(app, @IntensitySpinnerFRETValueChanged, true);
            app.IntensitySpinnerFRET_1.Tag = 'FRET_1';
            app.IntensitySpinnerFRET_1.Value = 1;
            app.IntensitySliderFRET_1 = uislider(app.LED1Tab);
            app.IntensitySliderFRET_1.Limits = [1 255];
            app.IntensitySliderFRET_1.MajorTicks = [1 50 100 150 200 255];
            app.IntensitySliderFRET_1.ValueChangedFcn = createCallbackFcn(app, @IntensitySliderFRETValueChanged, true);
            app.IntensitySliderFRET_1.MinorTicks = [10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180 190 200 210 220 230 240 250];
            app.IntensitySliderFRET_1.Tag = 'FRET_1';
            app.IntensitySliderFRET_1.Value = 1;
            app.ActiveSwitchLabel = uilabel(app.LED1Tab);
            app.ActiveSwitchLabel.Text = 'Active:';
            app.LEDLamp_1 = uilamp(app.LED1Tab);
            app.LEDSwitch_1 = uiswitch(app.LED1Tab, 'slider');
            app.LEDSwitch_1.Items = {'', ''};
            app.LEDSwitch_1.ItemsData = [0 1];
            app.LEDSwitch_1.Tag = 'FRET_1';
            app.LEDSwitch_1.Enable = 'off';
            app.LEDSwitch_1.Visible = 'off';
            app.LEDSwitch_1.Value = 0;
            app.LED2Tab = uitab(app.TabGroup2);
            app.LED2Tab.Tooltip = {'Grey = Inactive'; 'Green = Active'};
            app.LED2Tab.Title = 'LED2';
            app.SelectedLEDLabel = uilabel(app.LED2Tab);
            app.SelectedLEDLabel.Text = 'Selected LED';
            app.LEDDropDownFRET_2 = uidropdown(app.LED2Tab);
            app.LEDDropDownFRET_2.Items = {'LED1', 'LED2', 'LED3', 'LED4', 'LED5', 'LED6', 'LED7', 'LED8'};
            app.LEDDropDownFRET_2.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.LEDDropDownFRET_2.Tag = 'FRET_2';
            app.LEDDropDownFRET_2.Value = '2';
            app.TimeOnEditField_2Label_3 = uilabel(app.LED2Tab);
            app.TimeOnEditField_2Label_3.Text = 'LED Pulse duration';
            app.TimeOnmsEditFieldFRET_2 = uieditfield(app.LED2Tab, 'numeric');
            app.TimeOnmsEditFieldFRET_2.Limits = [3 Inf];
            app.TimeOnmsEditFieldFRET_2.ValueDisplayFormat = '%11.4g ms';
            app.TimeOnmsEditFieldFRET_2.ValueChangedFcn = createCallbackFcn(app, @TimePausemsEditFieldFRETValueChanged, true);
            app.TimeOnmsEditFieldFRET_2.Tag = 'FRET_2';
            app.TimeOnmsEditFieldFRET_2.Value = 3;
            app.IntensitySpinner_2Label_3 = uilabel(app.LED2Tab);
            app.IntensitySpinner_2Label_3.Text = 'Intensity';
            app.IntensitySpinnerFRET_2 = uispinner(app.LED2Tab);
            app.IntensitySpinnerFRET_2.Limits = [1 255];
            app.IntensitySpinnerFRET_2.RoundFractionalValues = 'on';
            app.IntensitySpinnerFRET_2.ValueChangedFcn = createCallbackFcn(app, @IntensitySpinnerFRETValueChanged, true);
            app.IntensitySpinnerFRET_2.Tag = 'FRET_2';
            app.IntensitySpinnerFRET_2.Value = 1;
            app.IntensitySliderFRET_2 = uislider(app.LED2Tab);
            app.IntensitySliderFRET_2.Limits = [1 255];
            app.IntensitySliderFRET_2.MajorTicks = [1 50 100 150 200 255];
            app.IntensitySliderFRET_2.ValueChangedFcn = createCallbackFcn(app, @IntensitySliderFRETValueChanged, true);
            app.IntensitySliderFRET_2.MinorTicks = [10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180 190 200 210 220 230 240 250];
            app.IntensitySliderFRET_2.Tag = 'FRET_2';
            app.IntensitySliderFRET_2.Value = 1;
            app.ActiveSwitchLabel_2 = uilabel(app.LED2Tab);
            app.ActiveSwitchLabel_2.Text = 'Active:';
            app.LEDLamp_2 = uilamp(app.LED2Tab);
            app.LEDLamp_2.Tag = 'FRET_2';
            app.LEDSwitch_2 = uiswitch(app.LED2Tab, 'slider');
            app.LEDSwitch_2.Items = {'', ''};
            app.LEDSwitch_2.ItemsData = [0 1];
            app.LEDSwitch_2.ValueChangedFcn = createCallbackFcn(app, @LEDSwitch_ValueChanged, true);
            app.LEDSwitch_2.Tag = 'FRET_2';
            app.LEDSwitch_2.Value = 0;
            app.LED3Tab = uitab(app.TabGroup2);
            app.LED3Tab.Title = 'LED3';
            app.LED3Tab.Tag = 'FRET_3';
            app.SelectedLEDLabel_2 = uilabel(app.LED3Tab);
            app.SelectedLEDLabel_2.Text = 'Selected LED';
            app.LEDDropDownFRET_3 = uidropdown(app.LED3Tab);
            app.LEDDropDownFRET_3.Items = {'LED1', 'LED2', 'LED3', 'LED4', 'LED5', 'LED6', 'LED7', 'LED8'};
            app.LEDDropDownFRET_3.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.LEDDropDownFRET_3.Tag = 'FRET_3';
            app.LEDDropDownFRET_3.Value = '3';
            app.TimeOnEditField_2Label_4 = uilabel(app.LED3Tab);
            app.TimeOnEditField_2Label_4.Text = 'LED Pulse duration';
            app.TimeOnmsEditFieldFRET_3 = uieditfield(app.LED3Tab, 'numeric');
            app.TimeOnmsEditFieldFRET_3.Limits = [3 Inf];
            app.TimeOnmsEditFieldFRET_3.ValueDisplayFormat = '%11.4g ms';
            app.TimeOnmsEditFieldFRET_3.ValueChangedFcn = createCallbackFcn(app, @TimePausemsEditFieldFRETValueChanged, true);
            app.TimeOnmsEditFieldFRET_3.Tag = 'FRET_3';
            app.TimeOnmsEditFieldFRET_3.Value = 3;
            app.IntensitySpinner_2Label_4 = uilabel(app.LED3Tab);
            app.IntensitySpinner_2Label_4.Text = 'Intensity';
            app.IntensitySpinnerFRET_3 = uispinner(app.LED3Tab);
            app.IntensitySpinnerFRET_3.Limits = [1 255];
            app.IntensitySpinnerFRET_3.RoundFractionalValues = 'on';
            app.IntensitySpinnerFRET_3.ValueChangedFcn = createCallbackFcn(app, @IntensitySpinnerFRETValueChanged, true);
            app.IntensitySpinnerFRET_3.Tag = 'FRET_3';
            app.IntensitySpinnerFRET_3.Value = 1;
            app.IntensitySliderFRET_3 = uislider(app.LED3Tab);
            app.IntensitySliderFRET_3.Limits = [1 255];
            app.IntensitySliderFRET_3.MajorTicks = [1 50 100 150 200 255];
            app.IntensitySliderFRET_3.ValueChangedFcn = createCallbackFcn(app, @IntensitySliderFRETValueChanged, true);
            app.IntensitySliderFRET_3.MinorTicks = [10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180 190 200 210 220 230 240 250];
            app.IntensitySliderFRET_3.Tag = 'FRET_3';
            app.IntensitySliderFRET_3.Value = 1;
            app.ActiveSwitchLabel_3 = uilabel(app.LED3Tab);
            app.ActiveSwitchLabel_3.Text = 'Active:';
            app.LEDLamp_3 = uilamp(app.LED3Tab);
            app.LEDLamp_3.Tag = 'FRET_3';
            app.LEDLamp_3.Tooltip = {'Grey = Inactive'; 'Green = Active'};
            app.LEDSwitch_3 = uiswitch(app.LED3Tab, 'slider');
            app.LEDSwitch_3.Items = {'', ''};
            app.LEDSwitch_3.ItemsData = [0 1];
            app.LEDSwitch_3.ValueChangedFcn = createCallbackFcn(app, @LEDSwitch_ValueChanged, true);
            app.LEDSwitch_3.Tag = 'FRET_3';
            app.LEDSwitch_3.Value = 0;
            app.LED4Tab = uitab(app.TabGroup2);
            app.LED4Tab.Title = 'LED4';
            app.LED4Tab.Tag = 'FRET_4';
            app.SelectedLEDLabel_3 = uilabel(app.LED4Tab);
            app.SelectedLEDLabel_3.Text = 'Selected LED';
            app.LEDDropDownFRET_4 = uidropdown(app.LED4Tab);
            app.LEDDropDownFRET_4.Items = {'LED1', 'LED2', 'LED3', 'LED4', 'LED5', 'LED6', 'LED7', 'LED8'};
            app.LEDDropDownFRET_4.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.LEDDropDownFRET_4.Tag = 'FRET_4';
            app.LEDDropDownFRET_4.Value = '4';
            app.TimeOnEditField_2Label_5 = uilabel(app.LED4Tab);
            app.TimeOnEditField_2Label_5.Text = 'LED Pulse duration';
            app.TimeOnmsEditFieldFRET_4 = uieditfield(app.LED4Tab, 'numeric');
            app.TimeOnmsEditFieldFRET_4.Limits = [3 Inf];
            app.TimeOnmsEditFieldFRET_4.ValueDisplayFormat = '%11.4g ms';
            app.TimeOnmsEditFieldFRET_4.ValueChangedFcn = createCallbackFcn(app, @TimePausemsEditFieldFRETValueChanged, true);
            app.TimeOnmsEditFieldFRET_4.Tag = 'FRET_4';
            app.TimeOnmsEditFieldFRET_4.Value = 3;
            app.IntensitySpinner_2Label_5 = uilabel(app.LED4Tab);
            app.IntensitySpinner_2Label_5.Text = 'Intensity';
            app.IntensitySpinnerFRET_4 = uispinner(app.LED4Tab);
            app.IntensitySpinnerFRET_4.Limits = [1 255];
            app.IntensitySpinnerFRET_4.RoundFractionalValues = 'on';
            app.IntensitySpinnerFRET_4.ValueChangedFcn = createCallbackFcn(app, @IntensitySpinnerFRETValueChanged, true);
            app.IntensitySpinnerFRET_4.Tag = 'FRET_4';
            app.IntensitySpinnerFRET_4.Value = 1;
            app.IntensitySliderFRET_4 = uislider(app.LED4Tab);
            app.IntensitySliderFRET_4.Limits = [1 255];
            app.IntensitySliderFRET_4.MajorTicks = [1 50 100 150 200 255];
            app.IntensitySliderFRET_4.ValueChangedFcn = createCallbackFcn(app, @IntensitySliderFRETValueChanged, true);
            app.IntensitySliderFRET_4.MinorTicks = [10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180 190 200 210 220 230 240 250];
            app.IntensitySliderFRET_4.Tag = 'FRET_4';
            app.IntensitySliderFRET_4.Value = 1;
            app.ActiveSwitchLabel_4 = uilabel(app.LED4Tab);
            app.ActiveSwitchLabel_4.Text = 'Active:';
            app.LEDLamp_4 = uilamp(app.LED4Tab);
            app.LEDLamp_4.Tag = 'FRET_4';
            app.LEDLamp_4.Tooltip = {'Grey = Inactive'; 'Green = Active'};
            app.LEDSwitch_4 = uiswitch(app.LED4Tab, 'slider');
            app.LEDSwitch_4.Items = {'', ''};
            app.LEDSwitch_4.ItemsData = [0 1];
            app.LEDSwitch_4.ValueChangedFcn = createCallbackFcn(app, @LEDSwitch_ValueChanged, true);
            app.LEDSwitch_4.Tag = 'FRET_4';
            app.LEDSwitch_4.Value = 0;
            app.GeneralTab = uitab(app.TabGroup2);
            app.GeneralTab.Title = 'General';
            app.TimePauseEditField_2Label = uilabel(app.GeneralTab);
            app.TimePauseEditField_2Label.Text = 'Time Pause';
            app.TimePausemsEditFieldFRET = uieditfield(app.GeneralTab, 'numeric');
            app.TimePausemsEditFieldFRET.Limits = [37 Inf];
            app.TimePausemsEditFieldFRET.ValueDisplayFormat = '%11.4g ms';
            app.TimePausemsEditFieldFRET.ValueChangedFcn = createCallbackFcn(app, @TimePausemsEditFieldFRETValueChanged, true);
            app.TimePausemsEditFieldFRET.Tag = 'FRET';
            app.TimePausemsEditFieldFRET.Value = 47;
            app.FrequencyEditFieldFRET = uieditfield(app.GeneralTab, 'numeric');
            app.FrequencyEditFieldFRET.Limits = [0 Inf];
            app.FrequencyEditFieldFRET.ValueDisplayFormat = '%11.4g Hz';
            app.FrequencyEditFieldFRET.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldFRETValueChanged, true);
            app.FrequencyEditFieldFRET.Tag = 'FRET';
            app.FrequencyEditFieldFRET.Value = 20;
            app.FrequencyEditField_2Label = uilabel(app.GeneralTab);
            app.FrequencyEditField_2Label.Text = 'Frequency';
            app.LightpulseTab = uitab(app.TabGroup2);
            app.LightpulseTab.Title = 'Lightpulse';
            app.SelectedLEDLabel_4 = uilabel(app.LightpulseTab);
            app.SelectedLEDLabel_4.Text = 'Selected LED';
            app.LEDDropDownPulse = uidropdown(app.LightpulseTab);
            app.LEDDropDownPulse.Items = {'LED1', 'LED2', 'LED3', 'LED4', 'LED5', 'LED6', 'LED7', 'LED8'};
            app.LEDDropDownPulse.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.LEDDropDownPulse.Tag = 'FRET_4';
            app.LEDDropDownPulse.Value = '4';
            app.TimeOnEditField_2Label_6 = uilabel(app.LightpulseTab);
            app.TimeOnEditField_2Label_6.Text = 'LED Pulse duration';
            app.EditFieldTimeDur = uieditfield(app.LightpulseTab, 'numeric');
            app.EditFieldTimeDur.Limits = [10 Inf];
            app.EditFieldTimeDur.ValueDisplayFormat = '%.0f ms';
            app.EditFieldTimeDur.Tag = 'FRET_4';
            app.EditFieldTimeDur.Value = 1000;
            app.IntensitySpinner_2Label_6 = uilabel(app.LightpulseTab);
            app.IntensitySpinner_2Label_6.Text = 'Intensity';
            app.IntensitySpinnerPulse = uispinner(app.LightpulseTab);
            app.IntensitySpinnerPulse.Limits = [1 255];
            app.IntensitySpinnerPulse.RoundFractionalValues = 'on';
            app.IntensitySpinnerPulse.Tag = 'FRET_4';
            app.IntensitySpinnerPulse.Value = 1;
            app.TimeOnEditField_2Label_7 = uilabel(app.LightpulseTab);
            app.TimeOnEditField_2Label_7.Text = 'Pulse after:';
            app.EditFieldTimePulse = uieditfield(app.LightpulseTab, 'numeric');
            app.EditFieldTimePulse.Limits = [1 Inf];
            app.EditFieldTimePulse.ValueDisplayFormat = '%.0f s';
            app.EditFieldTimePulse.Tag = 'FRET_4';
            app.EditFieldTimePulse.Value = 10;
            app.ActiveSwitchLabel_5 = uilabel(app.LightpulseTab);
            app.ActiveSwitchLabel_5.Text = 'Active:';
            app.LEDLampPulse = uilamp(app.LightpulseTab);
            app.LEDLampPulse.Tag = 'FRET_4';
            app.LEDLampPulse.Tooltip = {'Grey = Inactive'; 'Green = Active'};
            app.LEDSwitchPulse = uiswitch(app.LightpulseTab, 'slider');
            app.LEDSwitchPulse.Items = {'', ''};
            app.LEDSwitchPulse.ItemsData = [0 1];
            app.LEDSwitchPulse.ValueChangedFcn = createCallbackFcn(app, @LEDSwitch_ValueChanged, true);
            app.LEDSwitchPulse.Tag = 'Pulse';
            app.LEDSwitchPulse.Value = 0;
            app.LEDTab = uitab(app.TabGroup);
            app.LEDTab.Title = 'LED';
            app.GridLayout_2 = uigridlayout(app.LEDTab);
            app.UIAxes = uiaxes(app.GridLayout_2);
            title(app.UIAxes, 'Protocol')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Wavelength (nm)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YLim = [350 650];
            app.UIAxes.YTick = [300 400 500 600];
            app.LED1Label = uilabel(app.GridLayout_2);
            app.LED1Label.Text = 'LED #1';
            app.LED2Label = uilabel(app.GridLayout_2);
            app.LED2Label.Text = 'LED #2';
            app.LED3Label = uilabel(app.GridLayout_2);
            app.LED3Label.Text = 'LED #3';
            app.ComplexLEDexcitationLabel = uilabel(app.GridLayout_2);
            app.ComplexLEDexcitationLabel.Text = 'Complex LED excitation:';
            app.SelectedLEDinSlotLabel = uilabel(app.GridLayout_2);
            app.SelectedLEDinSlotLabel.Text = 'Selected LED ';
            app.LEDDropDownLED_1 = uidropdown(app.GridLayout_2);
            app.LEDDropDownLED_1.Items = {'LED1', 'LED2', 'LED3', 'LED4', 'LED5', 'LED6', 'LED7', 'LED8'};
            app.LEDDropDownLED_1.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.LEDDropDownLED_1.ValueChangedFcn = createCallbackFcn(app, @Chained_LEDValueChanged, true);
            app.LEDDropDownLED_1.Tag = 'LED_1';
            app.LEDDropDownLED_1.Value = '1';
            app.LEDDropDownLED_2 = uidropdown(app.GridLayout_2);
            app.LEDDropDownLED_2.Items = {'LED1', 'LED2', 'LED3', 'LED4', 'LED5', 'LED6', 'LED7', 'LED8'};
            app.LEDDropDownLED_2.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.LEDDropDownLED_2.ValueChangedFcn = createCallbackFcn(app, @Chained_LEDValueChanged, true);
            app.LEDDropDownLED_2.Tag = 'LED_2';
            app.LEDDropDownLED_2.Value = '2';
            app.LEDDropDownLED_3 = uidropdown(app.GridLayout_2);
            app.LEDDropDownLED_3.Items = {'LED1', 'LED2', 'LED3', 'LED4', 'LED5', 'LED6', 'LED7', 'LED8'};
            app.LEDDropDownLED_3.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.LEDDropDownLED_3.ValueChangedFcn = createCallbackFcn(app, @Chained_LEDValueChanged, true);
            app.LEDDropDownLED_3.Tag = 'LED_3';
            app.LEDDropDownLED_3.Value = '3';
            app.Label = uilabel(app.GridLayout_2);
            app.DropDown2Label = uilabel(app.GridLayout_2);
            app.DropDown2Label.Text = 'Drop Down2';
            app.Chained_LED_1 = uidropdown(app.GridLayout_2);
            app.Chained_LED_1.Items = {'LED #1', 'LED #2', 'LED #3', 'LED #4'};
            app.Chained_LED_1.ItemsData = {'1', '2', '3', '4'};
            app.Chained_LED_1.ValueChangedFcn = createCallbackFcn(app, @Chained_LEDValueChanged, true);
            app.Chained_LED_1.Value = '1';
            app.IntensitySpinnerLabel = uilabel(app.GridLayout_2);
            app.IntensitySpinnerLabel.Text = 'Intensity';
            app.IntensitySpinnerLED_1 = uispinner(app.GridLayout_2);
            app.IntensitySpinnerLED_1.Limits = [1 255];
            app.IntensitySpinnerLED_1.ValueChangedFcn = createCallbackFcn(app, @IntensitySpinnerFRETValueChanged, true);
            app.IntensitySpinnerLED_1.Tag = 'LED_1';
            app.IntensitySpinnerLED_1.Value = 1;
            app.IntensitySpinnerLED_2 = uispinner(app.GridLayout_2);
            app.IntensitySpinnerLED_2.Limits = [1 255];
            app.IntensitySpinnerLED_2.ValueChangedFcn = createCallbackFcn(app, @IntensitySpinnerFRETValueChanged, true);
            app.IntensitySpinnerLED_2.Tag = 'LED_2';
            app.IntensitySpinnerLED_2.Value = 1;
            app.IntensitySpinnerLED_3 = uispinner(app.GridLayout_2);
            app.IntensitySpinnerLED_3.Limits = [1 255];
            app.IntensitySpinnerLED_3.ValueChangedFcn = createCallbackFcn(app, @IntensitySpinnerFRETValueChanged, true);
            app.IntensitySpinnerLED_3.Tag = 'LED_3';
            app.IntensitySpinnerLED_3.Value = 1;
            app.DropDown3Label = uilabel(app.GridLayout_2);
            app.DropDown3Label.Text = 'Drop Down3';
            app.Chained_LED_2 = uidropdown(app.GridLayout_2);
            app.Chained_LED_2.Items = {'LED #1', 'LED #2', 'LED #3', 'LED #4', 'None'};
            app.Chained_LED_2.ItemsData = {'1', '2', '3', '4', '0'};
            app.Chained_LED_2.ValueChangedFcn = createCallbackFcn(app, @Chained_LEDValueChanged, true);
            app.Chained_LED_2.Value = '2';
            app.IntensitySliderLED_1 = uislider(app.GridLayout_2);
            app.IntensitySliderLED_1.Limits = [1 255];
            app.IntensitySliderLED_1.MajorTicks = [1 50 100 150 200 255];
            app.IntensitySliderLED_1.ValueChangedFcn = createCallbackFcn(app, @IntensitySliderFRETValueChanged, true);
            app.IntensitySliderLED_1.MinorTicks = [10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180 190 200 210 220 230 240 250];
            app.IntensitySliderLED_1.Tag = 'LED_1';
            app.IntensitySliderLED_1.Value = 1;
            app.IntensitySliderLED_2 = uislider(app.GridLayout_2);
            app.IntensitySliderLED_2.Limits = [1 255];
            app.IntensitySliderLED_2.MajorTicks = [1 50 100 150 200 255];
            app.IntensitySliderLED_2.ValueChangedFcn = createCallbackFcn(app, @IntensitySliderFRETValueChanged, true);
            app.IntensitySliderLED_2.MinorTicks = [10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180 190 200 210 220 230 240 250];
            app.IntensitySliderLED_2.Tag = 'LED_2';
            app.IntensitySliderLED_2.Value = 1;
            app.IntensitySliderLED_3 = uislider(app.GridLayout_2);
            app.IntensitySliderLED_3.Limits = [1 255];
            app.IntensitySliderLED_3.MajorTicks = [1 50 100 150 200 255];
            app.IntensitySliderLED_3.ValueChangedFcn = createCallbackFcn(app, @IntensitySliderFRETValueChanged, true);
            app.IntensitySliderLED_3.MinorTicks = [10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180 190 200 210 220 230 240 250];
            app.IntensitySliderLED_3.Tag = 'LED_3';
            app.IntensitySliderLED_3.Value = 1;
            app.DropDown4Label = uilabel(app.GridLayout_2);
            app.DropDown4Label.Text = 'Drop Down4';
            app.Chained_LED_3 = uidropdown(app.GridLayout_2);
            app.Chained_LED_3.Items = {'LED #1', 'LED #2', 'LED #3', 'LED #4', 'None'};
            app.Chained_LED_3.ItemsData = {'1', '2', '3', '4', '0'};
            app.Chained_LED_3.ValueChangedFcn = createCallbackFcn(app, @Chained_LEDValueChanged, true);
            app.Chained_LED_3.Value = '0';
            app.LEDpulsedurationLabel = uilabel(app.GridLayout_2);
            app.LEDpulsedurationLabel.Text = 'LED pulse duration';
            app.TimeOnmsEditFieldLED_1 = uieditfield(app.GridLayout_2, 'numeric');
            app.TimeOnmsEditFieldLED_1.Limits = [0.01 2147483647];
            app.TimeOnmsEditFieldLED_1.ValueDisplayFormat = '%11.4g ms';
            app.TimeOnmsEditFieldLED_1.ValueChangedFcn = createCallbackFcn(app, @Chained_LEDValueChanged, true);
            app.TimeOnmsEditFieldLED_1.Tag = 'LED_1';
            app.TimeOnmsEditFieldLED_1.Value = 4000;
            app.TimeOnmsEditFieldLED_2 = uieditfield(app.GridLayout_2, 'numeric');
            app.TimeOnmsEditFieldLED_2.Limits = [0.01 2147483647];
            app.TimeOnmsEditFieldLED_2.ValueDisplayFormat = '%11.4g ms';
            app.TimeOnmsEditFieldLED_2.ValueChangedFcn = createCallbackFcn(app, @Chained_LEDValueChanged, true);
            app.TimeOnmsEditFieldLED_2.Tag = 'LED_2';
            app.TimeOnmsEditFieldLED_2.Value = 4000;
            app.TimeOnmsEditFieldLED_3 = uieditfield(app.GridLayout_2, 'numeric');
            app.TimeOnmsEditFieldLED_3.Limits = [0.01 2147483647];
            app.TimeOnmsEditFieldLED_3.ValueDisplayFormat = '%11.4g ms';
            app.TimeOnmsEditFieldLED_3.ValueChangedFcn = createCallbackFcn(app, @Chained_LEDValueChanged, true);
            app.TimeOnmsEditFieldLED_3.Tag = 'LED_3';
            app.TimeOnmsEditFieldLED_3.Value = 1000;
            app.TimeOffLabel = uilabel(app.GridLayout_2);
            app.TimeOffLabel.Text = 'LED pulse pause';
            app.TimePausemsEditFieldLED_1 = uieditfield(app.GridLayout_2, 'numeric');
            app.TimePausemsEditFieldLED_1.Limits = [0 2147483647];
            app.TimePausemsEditFieldLED_1.ValueDisplayFormat = '%11.4g ms';
            app.TimePausemsEditFieldLED_1.ValueChangedFcn = createCallbackFcn(app, @Chained_LEDValueChanged, true);
            app.TimePausemsEditFieldLED_1.Tag = 'LED_1';
            app.TimePausemsEditFieldLED_2 = uieditfield(app.GridLayout_2, 'numeric');
            app.TimePausemsEditFieldLED_2.Limits = [0 2147483647];
            app.TimePausemsEditFieldLED_2.ValueDisplayFormat = '%11.4g ms';
            app.TimePausemsEditFieldLED_2.ValueChangedFcn = createCallbackFcn(app, @Chained_LEDValueChanged, true);
            app.TimePausemsEditFieldLED_2.Tag = 'LED_2';
            app.TimePausemsEditFieldLED_3 = uieditfield(app.GridLayout_2, 'numeric');
            app.TimePausemsEditFieldLED_3.Limits = [0 2147483647];
            app.TimePausemsEditFieldLED_3.ValueDisplayFormat = '%11.4g ms';
            app.TimePausemsEditFieldLED_3.ValueChangedFcn = createCallbackFcn(app, @Chained_LEDValueChanged, true);
            app.TimePausemsEditFieldLED_3.Tag = 'LED_3';
            app.GoButtonLED = uibutton(app.GridLayout_2, 'push');
            app.GoButtonLED.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButtonLED.Tag = 'LED';
            app.GoButtonLED.Text = 'Start Single LED';
            app.StopButtonLED = uibutton(app.GridLayout_2, 'push');
            app.StopButtonLED.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButtonLED.Text = 'Stop';
            app.DropDown5Label = uilabel(app.GridLayout_2);
            app.DropDown5Label.Text = 'Drop Down5';
            app.Chained_LED_4 = uidropdown(app.GridLayout_2);
            app.Chained_LED_4.Items = {'LED #1', 'LED #2', 'LED #3', 'LED #4', 'None'};
            app.Chained_LED_4.ItemsData = {'1', '2', '3', '4', '0'};
            app.Chained_LED_4.ValueChangedFcn = createCallbackFcn(app, @Chained_LEDValueChanged, true);
            app.Chained_LED_4.Value = '0';
            app.LED4Label = uilabel(app.GridLayout_2);
            app.LED4Label.Text = 'LED #4';
            app.LEDDropDownLED_4 = uidropdown(app.GridLayout_2);
            app.LEDDropDownLED_4.Items = {'LED1', 'LED2', 'LED3', 'LED4', 'LED5', 'LED6', 'LED7', 'LED8'};
            app.LEDDropDownLED_4.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.LEDDropDownLED_4.ValueChangedFcn = createCallbackFcn(app, @Chained_LEDValueChanged, true);
            app.LEDDropDownLED_4.Tag = 'LED_3';
            app.LEDDropDownLED_4.Value = '4';
            app.IntensitySpinnerLED_4 = uispinner(app.GridLayout_2);
            app.IntensitySpinnerLED_4.Limits = [1 255];
            app.IntensitySpinnerLED_4.ValueChangedFcn = createCallbackFcn(app, @IntensitySpinnerFRETValueChanged, true);
            app.IntensitySpinnerLED_4.Tag = 'LED_4';
            app.IntensitySpinnerLED_4.Value = 1;
            app.IntensitySliderLED_4 = uislider(app.GridLayout_2);
            app.IntensitySliderLED_4.Limits = [1 255];
            app.IntensitySliderLED_4.MajorTicks = [1 50 100 150 200 255];
            app.IntensitySliderLED_4.ValueChangedFcn = createCallbackFcn(app, @IntensitySliderFRETValueChanged, true);
            app.IntensitySliderLED_4.MinorTicks = [10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180 190 200 210 220 230 240 250];
            app.IntensitySliderLED_4.Tag = 'LED_4';
            app.IntensitySliderLED_4.Value = 1;
            app.TimeOnmsEditFieldLED_4 = uieditfield(app.GridLayout_2, 'numeric');
            app.TimeOnmsEditFieldLED_4.Limits = [0.01 2147483647];
            app.TimeOnmsEditFieldLED_4.ValueDisplayFormat = '%11.4g ms';
            app.TimeOnmsEditFieldLED_4.ValueChangedFcn = createCallbackFcn(app, @Chained_LEDValueChanged, true);
            app.TimeOnmsEditFieldLED_4.Tag = 'LED_3';
            app.TimeOnmsEditFieldLED_4.Value = 1000;
            app.TimePausemsEditFieldLED_4 = uieditfield(app.GridLayout_2, 'numeric');
            app.TimePausemsEditFieldLED_4.Limits = [0 2147483647];
            app.TimePausemsEditFieldLED_4.ValueDisplayFormat = '%11.4g ms';
            app.TimePausemsEditFieldLED_4.ValueChangedFcn = createCallbackFcn(app, @Chained_LEDValueChanged, true);
            app.TimePausemsEditFieldLED_4.Tag = 'LED_3';
            app.PausebetweenrepeatsLabel = uilabel(app.GridLayout_2);
            app.PausebetweenrepeatsLabel.Text = {'Pause between '; 'repeats'};
            app.TimePauseAllmsEditFieldLED = uieditfield(app.GridLayout_2, 'numeric');
            app.TimePauseAllmsEditFieldLED.Limits = [0 2147483647];
            app.TimePauseAllmsEditFieldLED.ValueDisplayFormat = '%11.4g ms';
            app.TimePauseAllmsEditFieldLED.ValueChangedFcn = createCallbackFcn(app, @Chained_LEDValueChanged, true);
            app.TimePauseAllmsEditFieldLED.Tag = 'LED_1';
            app.LEDDropDownLabel_2 = uilabel(app.GridLayout_2);
            app.LEDDropDownLabel_2.Text = 'LED Slot';
            app.RepeatsSpinnerLabel = uilabel(app.GridLayout_2);
            app.RepeatsSpinnerLabel.Text = '# Repeats';
            app.RepeatsSpinner = uispinner(app.GridLayout_2);
            app.RepeatsSpinner.Limits = [0 256];
            app.RepeatsSpinner.ValueChangedFcn = createCallbackFcn(app, @Chained_LEDValueChanged, true);
            app.RepeatsSpinner.Tooltip = {'Set to 0 for infinite repeats'};
            app.Label2 = uilabel(app.GridLayout_2);
            app.Label2.Text = 'Label2';
            app.StartComplexButton_2 = uibutton(app.GridLayout_2, 'push');
            app.StartComplexButton_2.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.StartComplexButton_2.Tag = 'Complex';
            app.StartComplexButton_2.Icon = fullfile(pathToMLAPP, 'Rainbow_Of_Light_Emitting_Diodes_clip_art.svg');
            app.StartComplexButton_2.Text = 'Start Complex';
            app.SwitchLEDonafterComplexCheckBox = uicheckbox(app.GridLayout_2);
            app.SwitchLEDonafterComplexCheckBox.Text = 'Switch LED on after Complex';
            app.LEDforSingleLEDDropDownLabel = uilabel(app.GridLayout_2);
            app.LEDforSingleLEDDropDownLabel.Text = 'LED #for Single LED';
            app.LEDforSingleLEDDropDown = uidropdown(app.GridLayout_2);
            app.LEDforSingleLEDDropDown.Items = {'LED #1', 'LED #2', 'LED #3', 'LED #4'};
            app.LEDforSingleLEDDropDown.ItemsData = [1 2 3 4];
            app.LEDforSingleLEDDropDown.Value = 1;
            app.LEDforAfterComplexLEDDropDown = uidropdown(app.GridLayout_2);
            app.LEDforAfterComplexLEDDropDown.Items = {'LED #1', 'LED #2', 'LED #3', 'LED #4'};
            app.LEDforAfterComplexLEDDropDown.ItemsData = [1 2 3 4];
            app.LEDforAfterComplexLEDDropDown.Value = 1;
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.GridLayout_3 = uigridlayout(app.SettingsTab);
            app.ComPortEditFieldLabel = uilabel(app.GridLayout_3);
            app.ComPortEditFieldLabel.Text = 'Com Port';
            app.ComPortEditField = uieditfield(app.GridLayout_3, 'text');
            app.ComPortEditField.ValueChangedFcn = createCallbackFcn(app, @ComPortEditFieldValueChanged, true);
            app.ComPortEditField.Value = 'COM7';
            app.MarkerRangeLabel = uilabel(app.GridLayout_3);
            app.MarkerRangeLabel.Text = 'Marker Range';
            app.GridLayout7 = uigridlayout(app.GridLayout_3);
            app.EditFieldmarkerRangeMin = uieditfield(app.GridLayout7, 'numeric');
            app.EditFieldmarkerRangeMin.ValueDisplayFormat = '%11.4g ';
            app.EditFieldmarkerRangeMin.Value = 40;
            app.EditFieldmarkerRangeMax = uieditfield(app.GridLayout7, 'numeric');
            app.EditFieldmarkerRangeMax.Value = 71;
            app.MaxCellSizeEditFieldLabel = uilabel(app.GridLayout_3);
            app.MaxCellSizeEditFieldLabel.Text = 'Max Cell Size';
            app.MaxCellSizeEditField = uieditfield(app.GridLayout_3, 'numeric');
            app.MaxCellSizeEditField.ValueDisplayFormat = '%11.7g';
            app.MaxCellSizeEditField.Value = 100000;
            app.LED1Label_2 = uilabel(app.GridLayout_3);
            app.LED1Label_2.Text = 'LED1:';
            app.NameLEDEditField_1 = uieditfield(app.GridLayout_3, 'text');
            app.NameLEDEditField_1.ValueChangedFcn = createCallbackFcn(app, @NameLEDEditFieldValueChanged, true);
            app.NameLEDEditField_1.Tag = '1';
            app.NameLEDEditField_1.Value = 'LED1';
            app.NameLED2Label = uilabel(app.GridLayout_3);
            app.NameLED2Label.Text = 'LED2:';
            app.NameLEDEditField_2 = uieditfield(app.GridLayout_3, 'text');
            app.NameLEDEditField_2.ValueChangedFcn = createCallbackFcn(app, @NameLEDEditFieldValueChanged, true);
            app.NameLEDEditField_2.Tag = '2';
            app.NameLEDEditField_2.Value = 'LED2';
            app.NameLED3Label = uilabel(app.GridLayout_3);
            app.NameLED3Label.Text = ' LED3:';
            app.NameLEDEditField_3 = uieditfield(app.GridLayout_3, 'text');
            app.NameLEDEditField_3.ValueChangedFcn = createCallbackFcn(app, @NameLEDEditFieldValueChanged, true);
            app.NameLEDEditField_3.Tag = '3';
            app.NameLEDEditField_3.Value = 'LED3';
            app.NameLED4Label = uilabel(app.GridLayout_3);
            app.NameLED4Label.Text = 'LED4:';
            app.NameLEDEditField_4 = uieditfield(app.GridLayout_3, 'text');
            app.NameLEDEditField_4.ValueChangedFcn = createCallbackFcn(app, @NameLEDEditFieldValueChanged, true);
            app.NameLEDEditField_4.Tag = '4';
            app.NameLEDEditField_4.Value = 'LED4';
            app.NameLED5Label = uilabel(app.GridLayout_3);
            app.NameLED5Label.Text = 'LED5:';
            app.NameLEDEditField_5 = uieditfield(app.GridLayout_3, 'text');
            app.NameLEDEditField_5.ValueChangedFcn = createCallbackFcn(app, @NameLEDEditFieldValueChanged, true);
            app.NameLEDEditField_5.Tag = '5';
            app.NameLEDEditField_5.Value = 'LED5';
            app.NameLED6Label = uilabel(app.GridLayout_3);
            app.NameLED6Label.Text = 'LED6:';
            app.NameLEDEditField_6 = uieditfield(app.GridLayout_3, 'text');
            app.NameLEDEditField_6.ValueChangedFcn = createCallbackFcn(app, @NameLEDEditFieldValueChanged, true);
            app.NameLEDEditField_6.Tag = '6';
            app.NameLEDEditField_6.Value = 'LED6';
            app.NameLED7Label = uilabel(app.GridLayout_3);
            app.NameLED7Label.Text = 'LED7:';
            app.NameLEDEditField_7 = uieditfield(app.GridLayout_3, 'text');
            app.NameLEDEditField_7.ValueChangedFcn = createCallbackFcn(app, @NameLEDEditFieldValueChanged, true);
            app.NameLEDEditField_7.Tag = '7';
            app.NameLEDEditField_7.Value = 'LED7';
            app.NameLED8Label = uilabel(app.GridLayout_3);
            app.NameLED8Label.Text = 'LED8:';
            app.NameLEDEditField_8 = uieditfield(app.GridLayout_3, 'text');
            app.NameLEDEditField_8.ValueChangedFcn = createCallbackFcn(app, @NameLEDEditFieldValueChanged, true);
            app.NameLEDEditField_8.Tag = '8';
            app.NameLEDEditField_8.Value = 'LED8';
            app.NameLabel = uilabel(app.GridLayout_3);
            app.NameLabel.Text = 'Name';
            app.WavelengthnmLabel = uilabel(app.GridLayout_3);
            app.WavelengthnmLabel.Text = 'Wavelength [nm]';
            app.WavelengthLED_1 = uieditfield(app.GridLayout_3, 'numeric');
            app.WavelengthLED_1.ValueChangedFcn = createCallbackFcn(app, @WavelengthLEDValueChanged, true);
            app.WavelengthLED_1.Value = 350;
            app.WavelengthLED_2 = uieditfield(app.GridLayout_3, 'numeric');
            app.WavelengthLED_2.ValueChangedFcn = createCallbackFcn(app, @WavelengthLEDValueChanged, true);
            app.WavelengthLED_2.Value = 400;
            app.WavelengthLED_3 = uieditfield(app.GridLayout_3, 'numeric');
            app.WavelengthLED_3.ValueChangedFcn = createCallbackFcn(app, @WavelengthLEDValueChanged, true);
            app.WavelengthLED_3.Value = 450;
            app.WavelengthLED_4 = uieditfield(app.GridLayout_3, 'numeric');
            app.WavelengthLED_4.ValueChangedFcn = createCallbackFcn(app, @WavelengthLEDValueChanged, true);
            app.WavelengthLED_4.Value = 500;
            app.WavelengthLED_5 = uieditfield(app.GridLayout_3, 'numeric');
            app.WavelengthLED_5.ValueChangedFcn = createCallbackFcn(app, @WavelengthLEDValueChanged, true);
            app.WavelengthLED_5.Value = 550;
            app.WavelengthLED_6 = uieditfield(app.GridLayout_3, 'numeric');
            app.WavelengthLED_6.ValueChangedFcn = createCallbackFcn(app, @WavelengthLEDValueChanged, true);
            app.WavelengthLED_6.Value = 600;
            app.WavelengthLED_7 = uieditfield(app.GridLayout_3, 'numeric');
            app.WavelengthLED_7.ValueChangedFcn = createCallbackFcn(app, @WavelengthLEDValueChanged, true);
            app.WavelengthLED_7.Value = 650;
            app.WavelengthLED_8 = uieditfield(app.GridLayout_3, 'numeric');
            app.WavelengthLED_8.ValueChangedFcn = createCallbackFcn(app, @WavelengthLEDValueChanged, true);
            app.WavelengthLED_8.Value = 700;
            app.Filter = uilabel(app.GridLayout_3);
            app.Filter.Text = 'Filter wavelength [nm]';
            app.FilterWavelengthLED_1 = uieditfield(app.GridLayout_3, 'text');
            app.FilterWavelengthLED_2 = uieditfield(app.GridLayout_3, 'text');
            app.FilterWavelengthLED_3 = uieditfield(app.GridLayout_3, 'text');
            app.FilterWavelengthLED_4 = uieditfield(app.GridLayout_3, 'text');
            app.FilterWavelengthLED_5 = uieditfield(app.GridLayout_3, 'text');
            app.FilterWavelengthLED_6 = uieditfield(app.GridLayout_3, 'text');
            app.FilterWavelengthLED_7 = uieditfield(app.GridLayout_3, 'text');
            app.FilterWavelengthLED_8 = uieditfield(app.GridLayout_3, 'text');
            app.Console = uieditfield(app.EnhancedLEDControlUIFigure, 'text');
            % Show the figure after all components are created
            app.EnhancedLEDControlUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Fret_Software

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EnhancedLEDControlUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EnhancedLEDControlUIFigure)
        end
    end
end